classdef demomatlab2016 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        demomatlab_gui          matlab.ui.Figure
        uipanel7                matlab.ui.container.Panel
        pushbutton_defaults     matlab.ui.control.Button
        slider_gamma            matlab.ui.control.Slider
        text_gamma              matlab.ui.control.Label
        text18                  matlab.ui.control.Label
        slider_contrast         matlab.ui.control.Slider
        text_contrast           matlab.ui.control.Label
        text16                  matlab.ui.control.Label
        slider_brightness       matlab.ui.control.Slider
        text_brightness         matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        slider_saturation       matlab.ui.control.Slider
        text_saturation         matlab.ui.control.Label
        text12                  matlab.ui.control.Label
        slider_hue              matlab.ui.control.Slider
        text_hue                matlab.ui.control.Label
        text10                  matlab.ui.control.Label
        uipanel6                matlab.ui.container.Panel
        pushbutton_snap         matlab.ui.control.Button
        exposure                matlab.ui.container.Panel
        text_exposure_time      matlab.ui.control.Label
        checkbox_auto_exposure  matlab.ui.control.CheckBox
        exposure_time           matlab.ui.control.Label
        slider_exposure_time    matlab.ui.control.Slider
        uipanel4                matlab.ui.container.Panel
        pushbutton_wbdefaults   matlab.ui.control.Button
        text_tint               matlab.ui.control.Label
        text_temp               matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        text3                   matlab.ui.control.Label
        slider_tint             matlab.ui.control.Slider
        slider_temp             matlab.ui.control.Slider
        pushbutton_awbOnePush   matlab.ui.control.Button
        uipanel_device          matlab.ui.container.Panel
        listbox_device          matlab.ui.control.ListBox
        axes_vedio              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bStop
        ToupcamData
        height
        width
        im
        videoTimer
        imgHandle
    end
    
    methods (Access = private)
        function onTimer(app)
            if ~isvalid(app) || app.bStop == 2
                return;
            end

            if ~isvalid(app.axes_vedio)
                return;
            end
     
            img = reshape(app.im, [3, app.width, app.height]);
            img = permute(img, [3 2 1]);
            img = img(:,:, [3 2 1]);
        
            if ~isvalid(app.axes_vedio)
                return;
            end
            
            if isempty(app.imgHandle) || ~isvalid(app.imgHandle)
                app.imgHandle = image(app.axes_vedio, img);
                app.axes_vedio.Visible = 'off';
                app.axes_vedio.Toolbar.Visible = 'off';
            else
                app.imgHandle.CData = img;
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function demomatlab_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.demomatlab_gui, 'onscreen');
            app.axes_vedio.Visible = 'off';
            app.axes_vedio.Toolbar.Visible = 'off';
            app.slider_exposure_time.MajorTicks = [];
            app.slider_exposure_time.MinorTicks = [];
            app.slider_temp.MajorTicks = [];
            app.slider_temp.MinorTicks = [];
            app.slider_tint.MajorTicks = [];
            app.slider_tint.MinorTicks = [];
            app.slider_hue.MajorTicks = [];
            app.slider_hue.MinorTicks = [];
            app.slider_saturation.MajorTicks = [];
            app.slider_saturation.MinorTicks = [];
            app.slider_brightness.MajorTicks = [];
            app.slider_brightness.MinorTicks = [];
            app.slider_contrast.MajorTicks = [];
            app.slider_contrast.MinorTicks = [];
            app.slider_gamma.MajorTicks = [];
            app.slider_gamma.MinorTicks = [];

            cla(app.axes_vedio);
            mex mexToupcam.cpp -ltoupcam;
            app.bStop=0;

            [devN,devList]=mexToupcam;
            if (devN==0)
                app.listbox_device.Items = {'No Device'};
            else
                setappdata(app.demomatlab_gui, 'Devices', devList);
                app.listbox_device.Items = {devList.name};
            end

            app.videoTimer = timer( ...
            'ExecutionMode', 'fixedSpacing', ...
            'Period', 0.03, ...           
            'BusyMode', 'drop', ...       
            'TimerFcn', @(~,~)onTimer(app));
        end

        % Value changed function: checkbox_auto_exposure
        function checkbox_auto_exposure_Callback(app, event)
            app.bStop = 1; %#ok<*NASGU>
            [a,time]=mexToupcam(2,0);
            time=roundn(time/1000,-2);
            if (a==0)
                app.checkbox_auto_exposure.Value = 0;
                app.slider_exposure_time.Enable = 'on';
                app.text_exposure_time.Visible = 'on';
                app.slider_exposure_time.Value = time;
                app.text_exposure_time.Text = num2str(time);
            else
                app.checkbox_auto_exposure.Value = 1;  
                app.slider_exposure_time.Enable = 'off';
                app.text_exposure_time.Visible  = 'off';
            end
            app.bStop = 0;
            uiresume;
        end

        % Close request function: demomatlab_gui
        function demomatlab_gui_CloseRequestFcn(app, event)
            app.bStop = 2;
            
            if ~isempty(app.videoTimer) && isvalid(app.videoTimer)
                stop(app.videoTimer);
                delete(app.videoTimer);
            end
            
            app.imgHandle = [];
            mexToupcam(0, 0);
            
            delete(app);
        end

        % Double-clicked callback: listbox_device
        function listbox_device_Callback(app, event)
            if isempty(event.InteractionInformation.Item)
                return;
            end
            app.bStop = 0;
            app.listbox_device.Enable = 'off';
            index = find(strcmp(app.listbox_device.Items, app.listbox_device.Value));
            nResolutionIndex=0;
            nSpeed=1;
            devList=getappdata(gcf,'Devices');
            [app.im, app.width, app.height, app.ToupcamData]=mexToupcam(nResolutionIndex, nSpeed, devList(index).id, index);
            app.slider_exposure_time.Enable = 'off';
            app.pushbutton_snap.Enable = 'on';
            app.pushbutton_awbOnePush.Enable = 'on';
            app.pushbutton_wbdefaults.Enable = 'on';
            app.pushbutton_defaults.Enable = 'on';
            app.slider_temp.Enable = 'on';
            app.slider_temp.Value  = app.ToupcamData.TOUPCAM_TEMP_DEF;
            app.slider_tint.Enable = 'on';
            app.slider_tint.Value = app.ToupcamData.TOUPCAM_TINT_DEF;
            app.text_temp.Visible = 'on';
            app.text_temp.Text = num2str(app.ToupcamData.TOUPCAM_TEMP_DEF);
            app.text_tint.Visible = 'on';
            app.text_tint.Text = num2str(app.ToupcamData.TOUPCAM_TINT_DEF);
            app.text_hue.Visible = 'on';
            app.text_hue.Text = num2str(app.ToupcamData.TOUPCAM_HUE_DEF);
            app.slider_hue.Enable = 'on';
            app.slider_hue.Value = app.ToupcamData.TOUPCAM_HUE_DEF;
            app.text_saturation.Visible = 'on';
            app.text_saturation.Text = num2str(app.ToupcamData.TOUPCAM_SATURATION_DEF);
            app.slider_saturation.Enable = 'on';
            app.slider_saturation.Value  = app.ToupcamData.TOUPCAM_SATURATION_DEF;
            app.text_brightness.Visible = 'on';
            app.text_brightness.Text = num2str(app.ToupcamData.TOUPCAM_BRIGHTNESS_DEF);
            app.slider_brightness.Enable = 'on';
            app.slider_brightness.Value  = app.ToupcamData.TOUPCAM_BRIGHTNESS_DEF;
            app.text_contrast.Visible = 'on';
            app.text_contrast.Text = num2str(app.ToupcamData.TOUPCAM_CONTRAST_DEF);
            app.slider_contrast.Enable = 'on';
            app.slider_contrast.Value = app.ToupcamData.TOUPCAM_CONTRAST_DEF;
            app.text_gamma.Visible = 'on';
            app.text_gamma.Text = num2str(app.ToupcamData.TOUPCAM_GAMMA_DEF);
            app.slider_gamma.Enable = 'on';
            app.slider_gamma.Value = app.ToupcamData.TOUPCAM_GAMMA_DEF;
            app.slider_gamma.Enable = 'on';
            app.checkbox_auto_exposure.Enable = 'on';

            cla(app.axes_vedio);
            if strcmp(app.videoTimer.Running, 'off')
             start(app.videoTimer);
            end
        end

        % Button pushed function: pushbutton_awbOnePush
        function pushbutton_awbOnePush_Callback(app, event)
            app.bStop = 1;
            [temp,tint]=mexToupcam(4,0);
            app.text_temp.Text = num2str(temp);
            app.text_tint.Text = num2str(tint);
            app.slider_temp.Value = temp;
            app.slider_tint.Value = tint;
            app.bStop = 0;
            uiresume;
        end

        % Button pushed function: pushbutton_defaults
        function pushbutton_defaults_Callback(app, event)
            app.bStop = 1;
            mexToupcam(13,0);
            app.text_hue.Text = num2str(app.ToupcamData.TOUPCAM_HUE_DEF);
            app.slider_hue.Value = app.ToupcamData.TOUPCAM_HUE_DEF;
            app.text_saturation.Text = num2str(app.ToupcamData.TOUPCAM_SATURATION_DEF);
            app.slider_saturation.Value = app.ToupcamData.TOUPCAM_SATURATION_DEF;
            app.text_brightness.Text = num2str(app.ToupcamData.TOUPCAM_BRIGHTNESS_DEF);
            app.slider_brightness.Value = app.ToupcamData.TOUPCAM_BRIGHTNESS_DEF;
            app.text_contrast.Text = num2str(app.ToupcamData.TOUPCAM_CONTRAST_DEF);
            app.slider_contrast.Value = app.ToupcamData.TOUPCAM_CONTRAST_DEF;
            app.text_gamma.Text = num2str(app.ToupcamData.TOUPCAM_GAMMA_DEF);
            app.slider_gamma.Value = app.ToupcamData.TOUPCAM_GAMMA_DEF;
            app.bStop = 0;
            uiresume;
        end

        % Button pushed function: pushbutton_snap
        function pushbutton_snap_Callback(app, event)
            mexToupcam(1,0);
        end

        % Button pushed function: pushbutton_wbdefaults
        function pushbutton_wbdefaults_Callback(app, event)
            app.bStop = 1;
            mexToupcam(12,0);
            app.text_temp.Text = num2str(app.ToupcamData.TOUPCAM_TEMP_DEF);
            app.slider_temp.Value = app.ToupcamData.TOUPCAM_TEMP_DEF;
            app.text_tint.Text = num2str(app.ToupcamData.TOUPCAM_TINT_DEF);
            app.slider_tint.Value = app.ToupcamData.TOUPCAM_TINT_DEF;
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_brightness
        function slider_brightness_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_brightness.Value);
            app.text_brightness.Text = num2str(val);
            mexToupcam(9,val);
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_contrast
        function slider_contrast_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_contrast.Value);
            app.text_contrast.Text = num2str(val);
            mexToupcam(10,val);
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_exposure_time
        function slider_exposure_time_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_exposure_time.Value);
            app.text_exposure_time.Visible = 'on';
            app.text_exposure_time.Text = num2str(roundn(val,-2));
            mexToupcam(3,val*1000);
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_gamma
        function slider_gamma_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_gamma.Value);
            app.text_gamma.Text = num2str(val);
            mexToupcam(11,val);
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_hue
        function slider_hue_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_hue.Value);
            app.text_hue.Text = num2str(val);
            mexToupcam(7,val);
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_saturation
        function slider_saturation_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_saturation.Value);
            app.text_saturation.Text = num2str(val);
            mexToupcam(8,val);
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_temp
        function slider_temp_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_temp.Value);
            app.text_temp.Text = num2str(val);
            mexToupcam(5,val);
            app.bStop = 0;
            uiresume;
        end

        % Value changed function: slider_tint
        function slider_tint_Callback(app, event)
            app.bStop = 1;
            val=round(app.slider_tint.Value);
            app.text_tint.Text = num2str(val);
            mexToupcam(6,val);
            app.bStop = 0;
            uiresume;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.demomatlab_gui = uifigure('Visible', 'off');
            colormap(app.demomatlab_gui, 'parula');
            app.demomatlab_gui.Name = 'Matlab Demo';
            app.demomatlab_gui.CloseRequestFcn = createCallbackFcn(app, @demomatlab_gui_CloseRequestFcn, true);
            app.demomatlab_gui.Tag = 'demomatlab_gui';
            app.axes_vedio = uiaxes(app.demomatlab_gui);
            app.axes_vedio.Tag = 'axes_vedio';
            app.axes_vedio.Visible = 'off';
            app.uipanel_device = uipanel(app.demomatlab_gui);
            app.uipanel_device.Title = 'Choose Device';
            app.uipanel_device.Tag = 'uipanel_device';
            app.listbox_device = uilistbox(app.uipanel_device);
            app.listbox_device.Items = {};
            app.listbox_device.Tag = 'listbox_device';
            app.listbox_device.DoubleClickedFcn = createCallbackFcn(app, @listbox_device_Callback, true);
            app.listbox_device.Value = {};
            app.uipanel4 = uipanel(app.demomatlab_gui);
            app.uipanel4.Title = 'White Balance';
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton_awbOnePush = uibutton(app.uipanel4, 'push');
            app.pushbutton_awbOnePush.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_awbOnePush_Callback, true);
            app.pushbutton_awbOnePush.Tag = 'pushbutton_awbOnePush';
            app.pushbutton_awbOnePush.Enable = 'off';
            app.pushbutton_awbOnePush.Text = 'White Balance';
            app.slider_temp = uislider(app.uipanel4);
            app.slider_temp.Limits = [2000 15000];
            app.slider_temp.MajorTicks = [];
            app.slider_temp.ValueChangedFcn = createCallbackFcn(app, @slider_temp_Callback, true);
            app.slider_temp.MinorTicks = [2000 2325 2650 2975 3300 3625 3950 4275 4600 4925 5250 5575 5900 6225 6550 6875 7200 7525 7850 8175 8500 8825 9150 9475 9800 10125 10450 10775 11100 11425 11750 12075 12400 12725 13050 13375 13700 14025 14350 14675 15000];
            app.slider_temp.Tag = 'slider_temp';
            app.slider_temp.Enable = 'off';
            app.slider_temp.Value = 6503;
            app.slider_tint = uislider(app.uipanel4);
            app.slider_tint.Limits = [200 2500];
            app.slider_tint.MajorTicks = [];
            app.slider_tint.ValueChangedFcn = createCallbackFcn(app, @slider_tint_Callback, true);
            app.slider_tint.MinorTicks = [200 292 384 476 568 660 752 844 936 1028 1120 1212 1304 1396 1488 1580 1672 1764 1856 1948 2040 2132 2224 2316 2408 2500];
            app.slider_tint.Tag = 'slider_tint';
            app.slider_tint.Enable = 'off';
            app.slider_tint.Value = 1000;
            app.text3 = uilabel(app.uipanel4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Temp:';
            app.text4 = uilabel(app.uipanel4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tint:';
            app.text_temp = uilabel(app.uipanel4);
            app.text_temp.Tag = 'text_temp';
            app.text_temp.Visible = 'off';
            app.text_temp.Text = '2000';
            app.text_tint = uilabel(app.uipanel4);
            app.text_tint.Tag = 'text_tint';
            app.text_tint.Visible = 'off';
            app.text_tint.Text = '200';
            app.pushbutton_wbdefaults = uibutton(app.uipanel4, 'push');
            app.pushbutton_wbdefaults.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_wbdefaults_Callback, true);
            app.pushbutton_wbdefaults.Tag = 'pushbutton_wbdefaults';
            app.pushbutton_wbdefaults.Enable = 'off';
            app.pushbutton_wbdefaults.Text = 'Defaults';
            app.exposure = uipanel(app.demomatlab_gui);
            app.exposure.Title = 'Exposure';
            app.exposure.Tag = 'exposure';
            app.slider_exposure_time = uislider(app.exposure);
            app.slider_exposure_time.Limits = [0.244 2000];
            app.slider_exposure_time.MajorTicks = [];
            app.slider_exposure_time.MajorTickLabels = {''};
            app.slider_exposure_time.ValueChangedFcn = createCallbackFcn(app, @slider_exposure_time_Callback, true);
            app.slider_exposure_time.MinorTicks = [0.244 50.244 100.244 150.244 200.244 250.244 300.244 350.244 400.244 450.244 500.244 550.244 600.244 650.244 700.244 750.244 800.244 850.244 900.244 950.244 1000.244 1050.244 1100.244 1150.244 1200.244 1250.244 1300.244 1350.244 1400.244 1450.244 1500.244 1550.244 1600.244 1650.244 1700.244 1750.244 1800.244 1850.244 1900.244 1950.244 2000];
            app.slider_exposure_time.Tag = 'slider_exposure_time';
            app.slider_exposure_time.Enable = 'off';
            app.slider_exposure_time.Value = 0.244;
            app.exposure_time = uilabel(app.exposure);
            app.exposure_time.Tag = 'exposure_time';
            app.exposure_time.Text = 'Exposure Time:';
            app.checkbox_auto_exposure = uicheckbox(app.exposure);
            app.checkbox_auto_exposure.ValueChangedFcn = createCallbackFcn(app, @checkbox_auto_exposure_Callback, true);
            app.checkbox_auto_exposure.Tag = 'checkbox_auto_exposure';
            app.checkbox_auto_exposure.Enable = 'off';
            app.checkbox_auto_exposure.Text = 'Auto exposure';
            app.checkbox_auto_exposure.Value = true;
            app.text_exposure_time = uilabel(app.exposure);
            app.text_exposure_time.Tag = 'text_exposure_time';
            app.text_exposure_time.Visible = 'off';
            app.text_exposure_time.Text = '0';
            app.uipanel6 = uipanel(app.demomatlab_gui);
            app.uipanel6.Title = 'Snap';
            app.uipanel6.Tag = 'uipanel6';
            app.pushbutton_snap = uibutton(app.uipanel6, 'push');
            app.pushbutton_snap.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_snap_Callback, true);
            app.pushbutton_snap.Tag = 'pushbutton_snap';
            app.pushbutton_snap.Enable = 'off';
            app.pushbutton_snap.Text = 'Snap';
            app.uipanel7 = uipanel(app.demomatlab_gui);
            app.uipanel7.Title = 'Color Adjustment';
            app.uipanel7.Tag = 'uipanel7';
            app.text10 = uilabel(app.uipanel7);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Hue';
            app.text_hue = uilabel(app.uipanel7);
            app.text_hue.Tag = 'text_hue';
            app.text_hue.Visible = 'off';
            app.text_hue.Text = '0';
            app.slider_hue = uislider(app.uipanel7);
            app.slider_hue.Limits = [-180 180];
            app.slider_hue.MajorTicks = [];
            app.slider_hue.MajorTickLabels = {''};
            app.slider_hue.ValueChangedFcn = createCallbackFcn(app, @slider_hue_Callback, true);
            app.slider_hue.MinorTicks = [-180 -171 -162 -153 -144 -135 -126 -117 -108 -99 -90 -81 -72 -63 -54 -45 -36 -27 -18 -9 0 9 18 27 36 45 54 63 72 81 90 99 108 117 126 135 144 153 162 171 180];
            app.slider_hue.Tag = 'slider_hue';
            app.slider_hue.Enable = 'off';
            app.text12 = uilabel(app.uipanel7);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Saturation';
            app.text_saturation = uilabel(app.uipanel7);
            app.text_saturation.Tag = 'text_saturation';
            app.text_saturation.Visible = 'off';
            app.text_saturation.Text = '128';
            app.slider_saturation = uislider(app.uipanel7);
            app.slider_saturation.Limits = [0 255];
            app.slider_saturation.MajorTicks = [];
            app.slider_saturation.ValueChangedFcn = createCallbackFcn(app, @slider_saturation_Callback, true);
            app.slider_saturation.MinorTicks = [0 8 16 24 32 40 48 56 64 72 80 88 96 104 112 120 128 136 144 152 160 168 176 184 192 200 208 216 224 232 240 248 255];
            app.slider_saturation.Tag = 'slider_saturation';
            app.slider_saturation.Enable = 'off';
            app.slider_saturation.Value = 128;
            app.text14 = uilabel(app.uipanel7);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Brightness';
            app.text_brightness = uilabel(app.uipanel7);
            app.text_brightness.Tag = 'text_brightness';
            app.text_brightness.Visible = 'off';
            app.text_brightness.Text = '0';
            app.slider_brightness = uislider(app.uipanel7);
            app.slider_brightness.Limits = [-64 64];
            app.slider_brightness.MajorTicks = [];
            app.slider_brightness.ValueChangedFcn = createCallbackFcn(app, @slider_brightness_Callback, true);
            app.slider_brightness.MinorTicks = [-64 -60 -56 -52 -48 -44 -40 -36 -32 -28 -24 -20 -16 -12 -8 -4 0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64];
            app.slider_brightness.Tag = 'slider_brightness';
            app.slider_brightness.Enable = 'off';
            app.text16 = uilabel(app.uipanel7);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Contrast';
            app.text_contrast = uilabel(app.uipanel7);
            app.text_contrast.Tag = 'text_contrast';
            app.text_contrast.Visible = 'off';
            app.text_contrast.Text = '0';
            app.slider_contrast = uislider(app.uipanel7);
            app.slider_contrast.Limits = [-100 100];
            app.slider_contrast.MajorTicks = [];
            app.slider_contrast.MajorTickLabels = {};
            app.slider_contrast.ValueChangedFcn = createCallbackFcn(app, @slider_contrast_Callback, true);
            app.slider_contrast.MinorTicks = [-100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.slider_contrast.Tag = 'slider_contrast';
            app.slider_contrast.Enable = 'off';
            app.text18 = uilabel(app.uipanel7);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Gamma';
            app.text_gamma = uilabel(app.uipanel7);
            app.text_gamma.Tag = 'text_gamma';
            app.text_gamma.Visible = 'off';
            app.text_gamma.Text = '100';
            app.slider_gamma = uislider(app.uipanel7);
            app.slider_gamma.Limits = [20 180];
            app.slider_gamma.MajorTicks = [];
            app.slider_gamma.MajorTickLabels = {};
            app.slider_gamma.ValueChangedFcn = createCallbackFcn(app, @slider_gamma_Callback, true);
            app.slider_gamma.MinorTicks = [20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180];
            app.slider_gamma.Tag = 'slider_gamma';
            app.slider_gamma.Enable = 'off';
            app.slider_gamma.Value = 100;
            app.pushbutton_defaults = uibutton(app.uipanel7, 'push');
            app.pushbutton_defaults.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_defaults_Callback, true);
            app.pushbutton_defaults.Tag = 'pushbutton_defaults';
            app.pushbutton_defaults.Enable = 'off';
            app.pushbutton_defaults.Text = 'Defaults';
            % Show the figure after all components are created
            app.demomatlab_gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demomatlab2016(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.demomatlab_gui)

                % Execute the startup function
                runStartupFcn(app, @(app)demomatlab_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.demomatlab_gui)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.demomatlab_gui)
        end
    end
end